classdef lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AlegetifunctiaButtonGroup       matlab.ui.container.ButtonGroup
        predefinitaButton               matlab.ui.control.RadioButton
        interactivButton                matlab.ui.control.RadioButton
        formulaDropDown                 matlab.ui.control.DropDown
        formulaEditField                matlab.ui.control.EditField
        bLabel                          matlab.ui.control.Label
        bEditField                      matlab.ui.control.EditField
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        SpecificatipreciziaButtonGroup  matlab.ui.container.ButtonGroup
        Label                           matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        AlegetimetodaButtonGroup        matlab.ui.container.ButtonGroup
        metodabisectieiButton           matlab.ui.control.RadioButton
        metodaNewtonButton              matlab.ui.control.RadioButton
        metodasecantelorButton          matlab.ui.control.RadioButton
        metodacoardelorButton           matlab.ui.control.RadioButton
        UITable                         matlab.ui.control.Table
        CalculeazaButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selectedFunctionType % predefined or interactive
        selectedAlgorithmType % bisection, Newton, etc.
        delta = 0.01
    end
    
    methods (Access = private)
        
        function [root, value, cycles, time] = bisectie(app, fn, point, eps)
            tStart = tic;
            a = point - app.delta;
            b = point;
            cycles = 0;
            
            aValue = fn(a);

            
            while (b - a > 2 * eps)
                c = (a + b)/2;
                cValue = fn(c);
                
                if aValue * cValue < 0
                    b = c;
                else
                    a = c;
                    aValue = cValue;
                end
                cycles = cycles + 1;
            end
            
            root = (a + b)/2;
            value = fn(root);
            tStop = toc;
            
            time = tStart - tStop;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.selectedFunctionType = app.AlegetifunctiaButtonGroup.SelectedObject;
            app.selectedAlgorithmType = app.metodabisectieiButton;
            app.UIAxes.XAxisLocation = "origin";
            app.UIAxes.YAxisLocation = "origin";
        end

        % Selection changed function: AlegetifunctiaButtonGroup
        function AlegetifunctiaButtonGroupSelectionChanged(app, event)
            app.selectedFunctionType = app.AlegetifunctiaButtonGroup.SelectedObject;
            
            if app.selectedFunctionType == app.predefinitaButton
                %app.aEditField.Enable = false;
                %app.bEditField.Enable = false;
                app.formulaEditField.Enable = false;
                app.formulaDropDown.Enable = true;
            else
                %app.aEditField.Enable = true;
                %app.bEditField.Enable = true;
                app.formulaEditField.Enable = true;
                app.formulaDropDown.Enable = false;
            end
        end

        % Selection changed function: AlegetimetodaButtonGroup
        function AlegetimetodaButtonGroupSelectionChanged(app, event)
            app.selectedAlgorithmType = app.AlegetimetodaButtonGroup.SelectedObject;
        end

        % Button pushed function: CalculeazaButton
        function CalculeazaButtonPushed(app, event)
            %set(app.UITable, 'Data', {});
            
            if app.selectedFunctionType == app.predefinitaButton
                funcText = app.formulaDropDown.Value;
            else
                funcText = app.formulaEditField.Value;
            end
            
            fn = str2func("@(x)" + funcText);
            
            a = str2double(app.aEditField.Value);
            b = str2double(app.bEditField.Value);
            
            xAxis = linspace(a, b);
            
            yAxis = fn(xAxis);
            
            plot(app.UIAxes, xAxis, yAxis);
            
            i = a + app.delta;
            prevValue = fn(a);
            currValue = fn(i);
            eps = 10.^app.Spinner.Value;
            tableData = [];
            
            while i < b 
                if prevValue * currValue < 0 
                    %diff sign, root at these coords
                    [root, value, cycles, time] = app.bisectie(fn, i, eps);
                    tableData = [tableData; root value cycles time];
                    root
                    value
                    cycles 
                    time
                end
                
                i = i + app.delta;
                prevValue = currValue;
                currValue = fn(i);
            end
            
            %app.UITable = array2table(tableData);
            

            %tableData
            
            set(app.UITable, 'Data', tableData);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlegetifunctiaButtonGroup = uibuttongroup(app.UIFigure);
            app.AlegetifunctiaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlegetifunctiaButtonGroupSelectionChanged, true);
            app.AlegetifunctiaButtonGroup.Title = '1) Alegeti functia';
            app.predefinitaButton = uiradiobutton(app.AlegetifunctiaButtonGroup);
            app.predefinitaButton.Text = 'predefinita';
            app.interactivButton = uiradiobutton(app.AlegetifunctiaButtonGroup);
            app.interactivButton.Text = 'interactiv';
            app.interactivButton.Value = true;
            app.formulaDropDown = uidropdown(app.AlegetifunctiaButtonGroup);
            app.formulaDropDown.Items = {'2*sin(3*x) - log(x.^3 - 1) + 4', 'sin(pi*x/6) - cos(x - 1)', 'exp(-x) - x.^3 + 8*cos(4*x)', 'x.^6 - 5.5*x.^5 + 6.18*x.^4 + 18.54*x.^3 - 56.9592*x.^2 + 55.9872*x - 19.3156', 'x.^6 - 0.7*x.^5 - 8.7*x.^4 + 5.58*x.^3 + 22.356*x.^2 - 8.39808*x - 19.3156', 'x.^6 - 2.4*x.^5 - 18.27*x.^4 + 23.216*x.^3 + 115.7*x.^2 + 19.5804*x - 164.818'};
            app.formulaDropDown.Editable = 'on';
            app.formulaDropDown.Value = '2*sin(3*x) - log(x.^3 - 1) + 4';
            app.formulaEditField = uieditfield(app.AlegetifunctiaButtonGroup, 'text');
            app.formulaEditField.Enable = 'off';
            app.formulaEditField.Value = 'x.^2 - 5';
            app.bLabel = uilabel(app.AlegetifunctiaButtonGroup);
            app.bLabel.Text = 'b ';
            app.bEditField = uieditfield(app.AlegetifunctiaButtonGroup, 'text');
            app.bEditField.Value = '5';
            app.aEditFieldLabel = uilabel(app.AlegetifunctiaButtonGroup);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.AlegetifunctiaButtonGroup, 'text');
            app.aEditField.Value = '-5';
            app.SpecificatipreciziaButtonGroup = uibuttongroup(app.UIFigure);
            app.SpecificatipreciziaButtonGroup.Title = '2) Specificati precizia';
            app.Label = uilabel(app.SpecificatipreciziaButtonGroup);
            app.Label.Text = '10 ^';
            app.Spinner = uispinner(app.SpecificatipreciziaButtonGroup);
            app.Spinner.Limits = [-10 -5];
            app.Spinner.Value = -5;
            app.AlegetimetodaButtonGroup = uibuttongroup(app.UIFigure);
            app.AlegetimetodaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlegetimetodaButtonGroupSelectionChanged, true);
            app.AlegetimetodaButtonGroup.Title = '3) Alegeti metoda';
            app.metodabisectieiButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodabisectieiButton.Text = 'metoda bisectiei';
            app.metodabisectieiButton.Value = true;
            app.metodaNewtonButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodaNewtonButton.Text = 'metoda Newton';
            app.metodasecantelorButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodasecantelorButton.Text = 'metoda secantelor';
            app.metodacoardelorButton = uiradiobutton(app.AlegetimetodaButtonGroup);
            app.metodacoardelorButton.Text = 'metoda coardelor';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Radacina'; 'Valoare'; 'Cicluri'; 'Timp'};
            app.UITable.RowName = {};
            app.CalculeazaButton = uibutton(app.UIFigure, 'push');
            app.CalculeazaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculeazaButtonPushed, true);
            app.CalculeazaButton.Text = 'Calculeaza';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end